classdef dualarm_gui_test3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        righteexincreaseButton     matlab.ui.control.Button
        righteexdecreaseButton     matlab.ui.control.Button
        righteeyincreaseButton     matlab.ui.control.Button
        righteezincreaseButton     matlab.ui.control.Button
        righteeryincreaseButton    matlab.ui.control.Button
        righteerzincreaseButton    matlab.ui.control.Button
        righteerxincreaseButton    matlab.ui.control.Button
        righteeydecreaseButton     matlab.ui.control.Button
        righteezdecreaseButton     matlab.ui.control.Button
        righteerxdecreaseButton    matlab.ui.control.Button
        righteerydecreaseButton    matlab.ui.control.Button
        righteerzdecreaseButton    matlab.ui.control.Button
        lefteerzincreaseButton     matlab.ui.control.Button
        lefteerzdecreaseButton     matlab.ui.control.Button
        lefteerydecreaseButton     matlab.ui.control.Button
        lefteeryincreaseButton     matlab.ui.control.Button
        lefteerxincreaseButton     matlab.ui.control.Button
        lefteerxdecreaseButton     matlab.ui.control.Button
        lefteezincreaseButton      matlab.ui.control.Button
        lefteezdecreaseButton      matlab.ui.control.Button
        lefteeydecreaseButton      matlab.ui.control.Button
        lefteeyincreaseButton      matlab.ui.control.Button
        lefteexdecreaseButton      matlab.ui.control.Button
        lefteexincreaseButton      matlab.ui.control.Button
        displaydualarmrobotButton  matlab.ui.control.Button
        rj1Slider                  matlab.ui.control.Slider
        rj1SliderLabel             matlab.ui.control.Label
        rj2Slider                  matlab.ui.control.Slider
        rj2SliderLabel             matlab.ui.control.Label
        rj3Slider                  matlab.ui.control.Slider
        rj3SliderLabel             matlab.ui.control.Label
        rj4Slider                  matlab.ui.control.Slider
        rj4SliderLabel             matlab.ui.control.Label
        rj5Slider                  matlab.ui.control.Slider
        rj5SliderLabel             matlab.ui.control.Label
        rj6Slider                  matlab.ui.control.Slider
        rj6SliderLabel             matlab.ui.control.Label
        rj7Slider                  matlab.ui.control.Slider
        rj7SliderLabel             matlab.ui.control.Label
        lj7Slider                  matlab.ui.control.Slider
        lj7SliderLabel             matlab.ui.control.Label
        lj6Slider                  matlab.ui.control.Slider
        lj6SliderLabel             matlab.ui.control.Label
        lj5Slider                  matlab.ui.control.Slider
        lj5SliderLabel             matlab.ui.control.Label
        lj4Slider                  matlab.ui.control.Slider
        lj4SliderLabel             matlab.ui.control.Label
        lj3Slider                  matlab.ui.control.Slider
        lj3SliderLabel             matlab.ui.control.Label
        lj2Slider                  matlab.ui.control.Slider
        lj2SliderLabel             matlab.ui.control.Label
        lj1Slider                  matlab.ui.control.Slider
        lj1SliderLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            syms tcp_l;
            tcp_l = 0;
            %           theta（连杆夹角） d（连杆偏距）      a（轴间矩）         alpha（轴间偏角,好像是绕下个关节的x轴）
            Left(1) = Link([0               0.084                0.0                   pi/2]); 
            Left(2) = Link([0               0.0                0.0                   pi/2]); 
            Left(3) = Link([0               0.27                0.0                   pi/2]); 
            Left(4) = Link([0               0.0                0.0                   -pi/2]); 
            Left(5) = Link([0               0.275                -0.005                   -pi/2]); 
            Left(6) = Link([0               0.0                0.0                   -pi/2]); 
            Left(7) = Link([0               0.0                0.023+tcp_l                   0]); 
            global robotleft
            robotleft = SerialLink(Left(1:7),'name','leftarm','base', transl(0.0, 0.121, 0.071)*trotx(-90));
            
            %           theta（连杆夹角） d（连杆偏距）      a（轴间矩）         alpha（轴间偏角,好像是绕下个关节的x轴）
            right(1) = Link([0               0.084                0.0                   -pi/2]); 
            right(2) = Link([0               0.0                0.0                   pi/2]); 
            right(3) = Link([0               0.27                0.0                   -pi/2]); 
            right(4) = Link([0               0.0                0.0                   pi/2]); 
            right(5) = Link([0               0.275                -0.005                   pi/2]); 
            right(6) = Link([0               0.0                0.0                   pi/2]); 
            right(7) = Link([0               0.0                -0.023+tcp_l                   0]); 
            global robotright
            robotright = SerialLink(right(1:7),'name','rightarm','base', transl(0.0, -0.121, 0.071)*trotx(90));

            global left_joint_pos_offset
            global right_joint_pos_offset
            left_joint_pos_offset = [pi/2,pi/2,pi/2,0,-pi/2,pi/2,pi];
            robotleft.offset = left_joint_pos_offset;
            right_joint_pos_offset = [-pi/2,pi/2,pi/2,0,pi/2,-pi/2,0];
            robotright.offset = right_joint_pos_offset;

            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            global azimuth elevation
            azimuth = 135;
            elevation = 20;


        end

        % Button pushed function: displaydualarmrobotButton
        function displaydualarmrobotButtonPushed(app, event)
            global robotleft
            left_init_pos = [0, 0, 0, 0, 0, 0, 0];
            robotleft.plot(left_init_pos);
            hold on;
            global robotright
            right_init_pos = [0, 0, 0, 0, 0, 0, 0];
            robotright.plot(right_init_pos);
            global azimuth elevation
            view(azimuth, elevation);
            
            
        end

        % Value changing function: lj1Slider
        function lj1SliderValueChanging(app, event)

            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            lj1pos = event.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;

            global robotleft 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotleft.plot(lq);
            % robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);

        end

        % Value changing function: lj2Slider
        function lj2SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            lj1pos = app.lj1Slider.Value;
            lj2pos = event.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;

            global robotleft 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotleft.plot(lq);
            % robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: lj3Slider
        function lj3SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = event.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;

            global robotleft 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotleft.plot(lq);
            % robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: lj4Slider
        function lj4SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = event.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;

            global robotleft 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotleft.plot(lq);
            % robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: lj5Slider
        function lj5SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = event.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;

            global robotleft 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotleft.plot(lq);
            % robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: lj6Slider
        function lj6SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = event.Value;
            lj7pos = app.lj7Slider.Value;

            global robotleft 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotleft.plot(lq);
            % robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: lj7Slider
        function lj7SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = event.Value;

            global robotleft

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotleft.plot(lq);
            % robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: rj1Slider
        function rj1SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            

            rj1pos = event.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;

            global robotright 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
            
        end

        % Value changing function: rj2Slider
        function rj2SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            rj1pos = app.rj1Slider.Value;
            rj2pos = event.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;

            global robotright 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: rj3Slider
        function rj3SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos

            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = event.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;

            global robotright 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: rj4Slider
        function rj4SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = event.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;

            global robotright 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: rj5Slider
        function rj5SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = event.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;

            global robotright 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: rj6Slider
        function rj6SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = event.Value;
            rj7pos = app.rj7Slider.Value;

            global robotright 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Value changing function: rj7Slider
        function rj7SliderValueChanging(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = event.Value;

            global robotright 

            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];

            robotright.plot(rq);
            global azimuth elevation
            view(azimuth, elevation);
        end

        % Button pushed function: lefteexincreaseButton
        function lefteexincreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_x = 0.02;
            delta_q = pinv(left_arm_jacob) * [delta_x, 0, 0, 0, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);


        end

        % Button pushed function: lefteexdecreaseButton
        function lefteexdecreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_x = -0.02;
            delta_q = pinv(left_arm_jacob) * [delta_x, 0, 0, 0, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteeyincreaseButton
        function lefteeyincreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_y = 0.02;
            delta_q = pinv(left_arm_jacob) * [0, delta_y, 0, 0, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteeydecreaseButton
        function lefteeydecreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_y = -0.02;
            delta_q = pinv(left_arm_jacob) * [0, delta_y, 0, 0, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteezincreaseButton
        function lefteezincreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_z = 0.02;
            delta_q = pinv(left_arm_jacob) * [0, 0, delta_z, 0, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteezdecreaseButton
        function lefteezdecreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_z = -0.02;
            delta_q = pinv(left_arm_jacob) * [0, 0, delta_z, 0, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteerxincreaseButton
        function lefteerxincreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_rx = 0.05;
            delta_q = pinv(left_arm_jacob) * [0, 0, 0, delta_rx, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteerxdecreaseButton
        function lefteerxdecreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_rx = -0.05;
            delta_q = pinv(left_arm_jacob) * [0, 0, 0, delta_rx, 0, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteeryincreaseButton
        function lefteeryincreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_ry = 0.05;
            delta_q = pinv(left_arm_jacob) * [0, 0, 0, 0, delta_ry, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteerydecreaseButton
        function lefteerydecreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_ry = -0.05;
            delta_q = pinv(left_arm_jacob) * [0, 0, 0, 0, delta_ry, 0]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteerzincreaseButton
        function lefteerzincreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_rz = 0.05;
            delta_q = pinv(left_arm_jacob) * [0, 0, 0, 0, 0, delta_rz]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: lefteerzdecreaseButton
        function lefteerzdecreaseButtonPushed(app, event)
            global lj1pos lj2pos lj3pos lj4pos lj5pos lj6pos lj7pos
            lj1pos = app.lj1Slider.Value;
            lj2pos = app.lj2Slider.Value;
            lj3pos = app.lj3Slider.Value;
            lj4pos = app.lj4Slider.Value;
            lj5pos = app.lj5Slider.Value;
            lj6pos = app.lj6Slider.Value;
            lj7pos = app.lj7Slider.Value;
            global robotleft 
            lq = [lj1pos, lj2pos, lj3pos, lj4pos, lj5pos, lj6pos, lj7pos];
            left_arm_jacob = robotleft.jacob0(lq);
            delta_rz = -0.05;
            delta_q = pinv(left_arm_jacob) * [0, 0, 0, 0, 0, delta_rz]';
            
            robotleft.plot(lq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.lj1Slider.Value = lj1pos + delta_q(1);
            app.lj2Slider.Value = lj2pos + delta_q(2);
            app.lj3Slider.Value = lj3pos + delta_q(3);
            app.lj4Slider.Value = lj4pos + delta_q(4);
            app.lj5Slider.Value = lj5pos + delta_q(5);
            app.lj6Slider.Value = lj6pos + delta_q(6);
            app.lj7Slider.Value = lj7pos + delta_q(7);
        end

        % Button pushed function: righteexincreaseButton
        function righteexincreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_x = 0.02;
            delta_q = pinv(right_arm_jacob) * [delta_x, 0, 0, 0, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteexdecreaseButton
        function righteexdecreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_x = -0.02;
            delta_q = pinv(right_arm_jacob) * [delta_x, 0, 0, 0, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteeyincreaseButton
        function righteeyincreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_y = 0.02;
            delta_q = pinv(right_arm_jacob) * [0, delta_y, 0, 0, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteeydecreaseButton
        function righteeydecreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_y = -0.02;
            delta_q = pinv(right_arm_jacob) * [0, delta_y, 0, 0, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteezincreaseButton
        function righteezincreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_z = 0.02;
            delta_q = pinv(right_arm_jacob) * [0, 0, delta_z, 0, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteezdecreaseButton
        function righteezdecreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_z = -0.02;
            delta_q = pinv(right_arm_jacob) * [0, 0, delta_z, 0, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteerxincreaseButton
        function righteerxincreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_rx = 0.05;
            delta_q = pinv(right_arm_jacob) * [0, 0, 0, delta_rx, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteerxdecreaseButton
        function righteerxdecreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_rx = -0.05;
            delta_q = pinv(right_arm_jacob) * [0, 0, 0, delta_rx, 0, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteeryincreaseButton
        function righteeryincreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_ry = 0.05;
            delta_q = pinv(right_arm_jacob) * [0, 0, 0, 0, delta_ry, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteerydecreaseButton
        function righteerydecreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_ry = -0.05;
            delta_q = pinv(right_arm_jacob) * [0, 0, 0, 0, delta_ry, 0]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteerzincreaseButton
        function righteerzincreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_rz = 0.05;
            delta_q = pinv(right_arm_jacob) * [0, 0, 0, 0, 0, delta_rz]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end

        % Button pushed function: righteerzdecreaseButton
        function righteerzdecreaseButtonPushed(app, event)
            global rj1pos rj2pos rj3pos rj4pos rj5pos rj6pos rj7pos
            rj1pos = app.rj1Slider.Value;
            rj2pos = app.rj2Slider.Value;
            rj3pos = app.rj3Slider.Value;
            rj4pos = app.rj4Slider.Value;
            rj5pos = app.rj5Slider.Value;
            rj6pos = app.rj6Slider.Value;
            rj7pos = app.rj7Slider.Value;
            global robotright 
            rq = [rj1pos, rj2pos, rj3pos, rj4pos, rj5pos, rj6pos, rj7pos];
            right_arm_jacob = robotright.jacob0(rq);
            delta_rz = -0.05;
            delta_q = pinv(right_arm_jacob) * [0, 0, 0, 0, 0, delta_rz]';
            robotright.plot(rq+delta_q');
            global azimuth elevation
            view(azimuth, elevation);
            app.rj1Slider.Value = rj1pos + delta_q(1);
            app.rj2Slider.Value = rj2pos + delta_q(2);
            app.rj3Slider.Value = rj3pos + delta_q(3);
            app.rj4Slider.Value = rj4pos + delta_q(4);
            app.rj5Slider.Value = rj5pos + delta_q(5);
            app.rj6Slider.Value = rj6pos + delta_q(6);
            app.rj7Slider.Value = rj7pos + delta_q(7);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lj1SliderLabel = uilabel(app.UIFigure);
            app.lj1SliderLabel.Text = 'lj1';
            app.lj1Slider = uislider(app.UIFigure);
            app.lj1Slider.Limits = [-3.14 3.14];
            app.lj1Slider.ValueChangingFcn = createCallbackFcn(app, @lj1SliderValueChanging, true);
            app.lj2SliderLabel = uilabel(app.UIFigure);
            app.lj2SliderLabel.Text = 'lj2';
            app.lj2Slider = uislider(app.UIFigure);
            app.lj2Slider.Limits = [-3.14 3.14];
            app.lj2Slider.ValueChangingFcn = createCallbackFcn(app, @lj2SliderValueChanging, true);
            app.lj3SliderLabel = uilabel(app.UIFigure);
            app.lj3SliderLabel.Text = 'lj3';
            app.lj3Slider = uislider(app.UIFigure);
            app.lj3Slider.Limits = [-3.14 3.14];
            app.lj3Slider.ValueChangingFcn = createCallbackFcn(app, @lj3SliderValueChanging, true);
            app.lj4SliderLabel = uilabel(app.UIFigure);
            app.lj4SliderLabel.Text = 'lj4';
            app.lj4Slider = uislider(app.UIFigure);
            app.lj4Slider.Limits = [-3.14 3.14];
            app.lj4Slider.ValueChangingFcn = createCallbackFcn(app, @lj4SliderValueChanging, true);
            app.lj5SliderLabel = uilabel(app.UIFigure);
            app.lj5SliderLabel.Text = 'lj5';
            app.lj5Slider = uislider(app.UIFigure);
            app.lj5Slider.Limits = [-3.14 3.14];
            app.lj5Slider.ValueChangingFcn = createCallbackFcn(app, @lj5SliderValueChanging, true);
            app.lj6SliderLabel = uilabel(app.UIFigure);
            app.lj6SliderLabel.Text = 'lj6';
            app.lj6Slider = uislider(app.UIFigure);
            app.lj6Slider.Limits = [-3.14 3.14];
            app.lj6Slider.ValueChangingFcn = createCallbackFcn(app, @lj6SliderValueChanging, true);
            app.lj7SliderLabel = uilabel(app.UIFigure);
            app.lj7SliderLabel.Text = 'lj7';
            app.lj7Slider = uislider(app.UIFigure);
            app.lj7Slider.Limits = [-3.14 3.14];
            app.lj7Slider.ValueChangingFcn = createCallbackFcn(app, @lj7SliderValueChanging, true);
            app.rj7SliderLabel = uilabel(app.UIFigure);
            app.rj7SliderLabel.Text = 'rj7';
            app.rj7Slider = uislider(app.UIFigure);
            app.rj7Slider.Limits = [-3.14 3.14];
            app.rj7Slider.ValueChangingFcn = createCallbackFcn(app, @rj7SliderValueChanging, true);
            app.rj6SliderLabel = uilabel(app.UIFigure);
            app.rj6SliderLabel.Text = 'rj6';
            app.rj6Slider = uislider(app.UIFigure);
            app.rj6Slider.Limits = [-3.14 3.14];
            app.rj6Slider.ValueChangingFcn = createCallbackFcn(app, @rj6SliderValueChanging, true);
            app.rj5SliderLabel = uilabel(app.UIFigure);
            app.rj5SliderLabel.Text = 'rj5';
            app.rj5Slider = uislider(app.UIFigure);
            app.rj5Slider.Limits = [-3.14 3.14];
            app.rj5Slider.ValueChangingFcn = createCallbackFcn(app, @rj5SliderValueChanging, true);
            app.rj4SliderLabel = uilabel(app.UIFigure);
            app.rj4SliderLabel.Text = 'rj4';
            app.rj4Slider = uislider(app.UIFigure);
            app.rj4Slider.Limits = [-3.14 3.14];
            app.rj4Slider.ValueChangingFcn = createCallbackFcn(app, @rj4SliderValueChanging, true);
            app.rj3SliderLabel = uilabel(app.UIFigure);
            app.rj3SliderLabel.Text = 'rj3';
            app.rj3Slider = uislider(app.UIFigure);
            app.rj3Slider.Limits = [-3.14 3.14];
            app.rj3Slider.ValueChangingFcn = createCallbackFcn(app, @rj3SliderValueChanging, true);
            app.rj2SliderLabel = uilabel(app.UIFigure);
            app.rj2SliderLabel.Text = 'rj2';
            app.rj2Slider = uislider(app.UIFigure);
            app.rj2Slider.Limits = [-3.14 3.14];
            app.rj2Slider.ValueChangingFcn = createCallbackFcn(app, @rj2SliderValueChanging, true);
            app.rj1SliderLabel = uilabel(app.UIFigure);
            app.rj1SliderLabel.Text = 'rj1';
            app.rj1Slider = uislider(app.UIFigure);
            app.rj1Slider.Limits = [-3.14 3.14];
            app.rj1Slider.ValueChangingFcn = createCallbackFcn(app, @rj1SliderValueChanging, true);
            app.displaydualarmrobotButton = uibutton(app.UIFigure, 'push');
            app.displaydualarmrobotButton.ButtonPushedFcn = createCallbackFcn(app, @displaydualarmrobotButtonPushed, true);
            app.displaydualarmrobotButton.Text = 'display dual arm robot';
            app.lefteexincreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteexincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteexincreaseButtonPushed, true);
            app.lefteexincreaseButton.Text = 'left ee x increase';
            app.lefteexdecreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteexdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteexdecreaseButtonPushed, true);
            app.lefteexdecreaseButton.Text = 'left ee x decrease';
            app.lefteeyincreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteeyincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteeyincreaseButtonPushed, true);
            app.lefteeyincreaseButton.Text = 'left ee y increase';
            app.lefteeydecreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteeydecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteeydecreaseButtonPushed, true);
            app.lefteeydecreaseButton.Text = 'left ee y decrease';
            app.lefteezdecreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteezdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteezdecreaseButtonPushed, true);
            app.lefteezdecreaseButton.Text = 'left ee z decrease';
            app.lefteezincreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteezincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteezincreaseButtonPushed, true);
            app.lefteezincreaseButton.Text = 'left ee z increase';
            app.lefteerxdecreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteerxdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteerxdecreaseButtonPushed, true);
            app.lefteerxdecreaseButton.Text = 'left ee rx decrease';
            app.lefteerxincreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteerxincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteerxincreaseButtonPushed, true);
            app.lefteerxincreaseButton.Text = 'left ee rx increase';
            app.lefteeryincreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteeryincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteeryincreaseButtonPushed, true);
            app.lefteeryincreaseButton.Text = 'left ee ry increase';
            app.lefteerydecreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteerydecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteerydecreaseButtonPushed, true);
            app.lefteerydecreaseButton.Text = 'left ee ry decrease';
            app.lefteerzdecreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteerzdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteerzdecreaseButtonPushed, true);
            app.lefteerzdecreaseButton.Text = 'left ee rz decrease';
            app.lefteerzincreaseButton = uibutton(app.UIFigure, 'push');
            app.lefteerzincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @lefteerzincreaseButtonPushed, true);
            app.lefteerzincreaseButton.Text = 'left ee rz increase';
            app.righteerzdecreaseButton = uibutton(app.UIFigure, 'push');
            app.righteerzdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteerzdecreaseButtonPushed, true);
            app.righteerzdecreaseButton.Text = 'right ee rz decrease';
            app.righteerydecreaseButton = uibutton(app.UIFigure, 'push');
            app.righteerydecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteerydecreaseButtonPushed, true);
            app.righteerydecreaseButton.Text = 'right ee ry decrease';
            app.righteerxdecreaseButton = uibutton(app.UIFigure, 'push');
            app.righteerxdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteerxdecreaseButtonPushed, true);
            app.righteerxdecreaseButton.Text = 'right ee rx decrease';
            app.righteezdecreaseButton = uibutton(app.UIFigure, 'push');
            app.righteezdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteezdecreaseButtonPushed, true);
            app.righteezdecreaseButton.Text = 'right ee z decrease';
            app.righteeydecreaseButton = uibutton(app.UIFigure, 'push');
            app.righteeydecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteeydecreaseButtonPushed, true);
            app.righteeydecreaseButton.Text = 'right ee y decrease';
            app.righteerxincreaseButton = uibutton(app.UIFigure, 'push');
            app.righteerxincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteerxincreaseButtonPushed, true);
            app.righteerxincreaseButton.Text = 'right ee rx increase';
            app.righteerzincreaseButton = uibutton(app.UIFigure, 'push');
            app.righteerzincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteerzincreaseButtonPushed, true);
            app.righteerzincreaseButton.Text = 'right ee rz increase';
            app.righteeryincreaseButton = uibutton(app.UIFigure, 'push');
            app.righteeryincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteeryincreaseButtonPushed, true);
            app.righteeryincreaseButton.Text = 'right ee ry increase';
            app.righteezincreaseButton = uibutton(app.UIFigure, 'push');
            app.righteezincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteezincreaseButtonPushed, true);
            app.righteezincreaseButton.Text = 'right ee z increase';
            app.righteeyincreaseButton = uibutton(app.UIFigure, 'push');
            app.righteeyincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteeyincreaseButtonPushed, true);
            app.righteeyincreaseButton.Text = 'right ee y increase';
            app.righteexdecreaseButton = uibutton(app.UIFigure, 'push');
            app.righteexdecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteexdecreaseButtonPushed, true);
            app.righteexdecreaseButton.Text = 'right ee x decrease';
            app.righteexincreaseButton = uibutton(app.UIFigure, 'push');
            app.righteexincreaseButton.ButtonPushedFcn = createCallbackFcn(app, @righteexincreaseButtonPushed, true);
            app.righteexincreaseButton.Text = 'right ee x increase';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dualarm_gui_test3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end